classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LidarWeightSliderLabel        matlab.ui.control.Label
        LidarWeightSlider             matlab.ui.control.Slider
        SmoothingFunctionButtonGroup  matlab.ui.container.ButtonGroup
        WeightedAverageButton         matlab.ui.control.RadioButton
        LowpassButton                 matlab.ui.control.RadioButton
        HighpassButton                matlab.ui.control.RadioButton
        BandpassButton                matlab.ui.control.RadioButton
        HighFreqCutoffLabel           matlab.ui.control.Label
        HighFreqCutoffSlider          matlab.ui.control.Slider
        LowFreqCutoffSliderLabel      matlab.ui.control.Label
        LowFreqCutoffSlider           matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: SmoothingFunctionButtonGroup
        function SmoothingFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.SmoothingFunctionButtonGroup.SelectedObject;
            if app.WeightedAverageButton.Value 
                app.LidarWeightSlider.Visible = true;
                app.HighFreqCutoffSlider.Visible = false;
                app.LowFreqCutoffSlider.Visible = false;
            elseif app.LowpassButton.Value 
                app.LidarWeightSlider.Visible = false;
                app.HighFreqCutoffSlider.Visible = false;
                app.LowFreqCutoffSlider.Visible = true;
            elseif app.HighpassButton.Value 
                app.LidarWeightSlider.Visible = false;
                app.HighFreqCutoffSlider.Visible = true;
                app.LowFreqCutoffSlider.Visible = false;
            elseif app.BandpassButton.Value 
                app.LidarWeightSlider.Visible = false;
                app.HighFreqCutoffSlider.Visible = true;
                app.LowFreqCutoffSlider.Visible = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LidarWeightSliderLabel = uilabel(app.UIFigure);
            app.LidarWeightSliderLabel.Text = 'Lidar Weight';
            app.LidarWeightSlider = uislider(app.UIFigure);
            app.LidarWeightSlider.Limits = [0 1];
            app.SmoothingFunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.SmoothingFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SmoothingFunctionButtonGroupSelectionChanged, true);
            app.SmoothingFunctionButtonGroup.Title = 'Smoothing Function';
            app.WeightedAverageButton = uiradiobutton(app.SmoothingFunctionButtonGroup);
            app.WeightedAverageButton.Text = 'Weighted Average';
            app.WeightedAverageButton.Value = true;
            app.LowpassButton = uiradiobutton(app.SmoothingFunctionButtonGroup);
            app.LowpassButton.Text = 'Low-pass';
            app.HighpassButton = uiradiobutton(app.SmoothingFunctionButtonGroup);
            app.HighpassButton.Text = 'High-pass';
            app.BandpassButton = uiradiobutton(app.SmoothingFunctionButtonGroup);
            app.BandpassButton.Text = 'Band-pass';
            app.HighFreqCutoffLabel = uilabel(app.UIFigure);
            app.HighFreqCutoffLabel.Text = {'High Freq'; 'Cut-off'};
            app.HighFreqCutoffSlider = uislider(app.UIFigure);
            app.HighFreqCutoffSlider.Limits = [0 1];
            app.LowFreqCutoffSliderLabel = uilabel(app.UIFigure);
            app.LowFreqCutoffSliderLabel.Text = {'Low Freq'; 'Cut-off'};
            app.LowFreqCutoffSlider = uislider(app.UIFigure);
            app.LowFreqCutoffSlider.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end